classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ResultsTextArea              matlab.ui.control.TextArea
        ResultsTextAreaLabel         matlab.ui.control.Label
        CalculateDopplerButton       matlab.ui.control.Button
        AngledegreesEditField        matlab.ui.control.NumericEditField
        AngledegreesEditFieldLabel   matlab.ui.control.Label
        SpeedmsEditField             matlab.ui.control.NumericEditField
        SpeedmsEditFieldLabel        matlab.ui.control.Label
        CarrierFrequencyHzEditField  matlab.ui.control.NumericEditField
        CarrierFrequencyHzEditFieldLabel  matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            
        end

        % Button pushed function: CalculateDopplerButton
        function CalculateDopplerButtonPushed(app, event)
            
                % Read inputs
                fc = app.CarrierFrequencyHzEditField.Value;
                v = app.SpeedmsEditField.Value;
                theta = app.AngledegreesEditField.Value;

                % Call external logic
                result = dopplerCalculator(fc, v, theta);

                % Display results
                if isinf(result.Tc)
                    TcText = "Infinite";
                else
                    TcText = sprintf("%.3f ms", result.Tc*1e3);
                end

                app.ResultsTextArea.Value = sprintf( ...
                    "Carrier Frequency: %.2e Hz\n" + ...
                    "Speed: %.2f m/s\n" + ...
                    "Angle: %.1f°\n" + ...
                    "Wavelength: %.4f m\n" + ...
                    "Doppler Shift: %.2f Hz\n" + ...
                    "Received Frequency: %.2e Hz\n" + ...
                    "Coherence Time: %s\n" + ...
                    "Channel Type: %s", ...
                    fc, v, theta, result.lambda, ...
                    result.fD, result.f_rx, TcText, result.fading ...
                    );

                % Plot Doppler vs Speed
                c = 3e8;
                lambda = c / fc;
                vRange = 0:1:100;
                fD_v = (vRange / lambda) .* cosd(theta);

                cla(app.UIAxes)
                plot(app.UIAxes, vRange, fD_v, 'LineWidth', 2)
                grid(app.UIAxes, 'on')
                xlabel(app.UIAxes, 'Speed (m/s)')
                ylabel(app.UIAxes, 'Doppler Shift (Hz)')
                title(app.UIAxes, 'Doppler Shift vs Speed')

                % Plot Doppler vs Angle
                thetaRange = 0:1:360;
                fD_theta = (v / lambda) .* cosd(thetaRange);

                cla(app.UIAxes2)
                plot(app.UIAxes2, thetaRange, fD_theta, 'LineWidth', 2)
                grid(app.UIAxes2, 'on')
                xlabel(app.UIAxes2, 'Angle (degrees)')
                ylabel(app.UIAxes2, 'Doppler Shift (Hz)')
                title(app.UIAxes2, 'Doppler Shift vs Angle')
           

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'DopplerVsSpeedAxes')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'DopplerVsAngleAxes')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CarrierFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.CarrierFrequencyHzEditFieldLabel.Text = 'Carrier Frequency (Hz)';
            app.CarrierFrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.CarrierFrequencyHzEditField.Tag = 'CarrierFreqEditField';
            app.CarrierFrequencyHzEditField.Value = 2000000000;
            app.SpeedmsEditFieldLabel = uilabel(app.UIFigure);
            app.SpeedmsEditFieldLabel.Text = 'Speed (m/s)';
            app.SpeedmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpeedmsEditField.Tag = 'SpeedEditField';
            app.SpeedmsEditField.Value = 30;
            app.AngledegreesEditFieldLabel = uilabel(app.UIFigure);
            app.AngledegreesEditFieldLabel.Text = 'Angle (degrees)';
            app.AngledegreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngledegreesEditField.Tag = 'AngleEditField';
            app.CalculateDopplerButton = uibutton(app.UIFigure, 'push');
            app.CalculateDopplerButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDopplerButtonPushed, true);
            app.CalculateDopplerButton.Tag = 'CalculateButton';
            app.CalculateDopplerButton.Text = 'Calculate Doppler';
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Text = 'Results ';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            app.ResultsTextArea.Tag = 'ResultsTextArea';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end